classdef NewtonRapson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewtonrapsonUIFigure         matlab.ui.Figure
        Image                        matlab.ui.control.Image
        TITULO_2                     matlab.ui.control.Label
        TITULO                       matlab.ui.control.Label
        Tabladevalores               matlab.ui.control.Table
        Configgrafica                matlab.ui.container.Panel
        tammarcardor                 matlab.ui.control.Slider
        TamaodemarcadorLabel         matlab.ui.control.Label
        lineagrosor                  matlab.ui.control.Slider
        GrosordelineasSliderLabel    matlab.ui.control.Label
        Limf                         matlab.ui.control.NumericEditField
        LimitefinalEditFieldLabel    matlab.ui.control.Label
        Limi                         matlab.ui.control.NumericEditField
        LimiteinicialEditFieldLabel  matlab.ui.control.Label
        Datos                        matlab.ui.container.Panel
        X0                           matlab.ui.control.NumericEditField
        X0EditFieldLabel             matlab.ui.control.Label
        Calcular                     matlab.ui.control.Button
        Errorminimo                  matlab.ui.control.NumericEditField
        ErrorminimoEditFieldLabel    matlab.ui.control.Label
        Niteraciones                 matlab.ui.control.NumericEditField
        IteracionesaprobarEditFieldLabel  matlab.ui.control.Label
        Deriva                       matlab.ui.control.EditField
        DerivadadelafuncionEditFieldLabel  matlab.ui.control.Label
        Funcion                      matlab.ui.control.EditField
        FuncionFxEditFieldLabel      matlab.ui.control.Label
        Grafica                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        val
        iteracion=1;
        mini
        maxi
        funcejex
        funcejey
        grosor
        tmar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Tabladevalores.Data=app.val;
            app.Tabladevalores.BackgroundColor=[0.29019607843 0.53333333333 0.85098039215];
        end

        % Button pushed function: Calcular
        function CalcularPushed(app, event)
            app.val={'','','','','',0};
            cla(app.Grafica,"reset");
            %config grafica
            app.Grafica.XGrid='on';
            app.Grafica.YGrid='on';
            app.Grafica.Title.String='Graficacion de las iteraciones';
            app.Grafica.XLabel.String='X';
            app.Grafica.YLabel.String='Y';
            %%%%
            funciono = app.Funcion.Value;
            syms f(x) h(x)
            app.grosor=app.lineagrosor.Value;
            app.tmar=app.tammarcardor.Value;
            app.mini=app.Limi.Value;
            aa=app.mini;
            app.maxi=app.Limf.Value;
            bb=app.maxi;
            f(x)=str2sym(funciono);
            h(x)=diff(f);
            app.Deriva.Value=char(h);
            errormin=app.Errorminimo.Value;
            imax=app.Niteraciones.Value;
            errorcal=50000; % Se inicialica con cualquier numero
            logico=878; % Se inicialica con cualquier numero
            x1=app.X0.Value;
            app.funcejex=aa:0.001:bb;
            app.funcejey=f(aa:0.001:bb);
            plot(app.Grafica,app.funcejex,app.funcejey,'LineWidth',app.grosor);
            hold(app.Grafica,'on');
            %grafica de la funcion-----------------------------------------%
            app.Tabladevalores.BackgroundColor=[0.29019607843 0.53333333333 0.85098039215; 0.42352941176 0.60784313725 0.85098039215];
            
            while logico~=1
                trad=sprintf('%i',app.iteracion);
                %----------------------------------------------------------%
                %Metodo
                tradd=sprintf('%.6f',x1);
                x2=double(x1-(f(x1)/(h(x1))));
                trad0=sprintf('%.6f',x2);
                fx1=double(f(x1));
                trad1=sprintf('%.6f',fx1);
                fh2=double(h(x1));
                trad2=sprintf('%.6f',fh2);
                %----------------------------------------------------------%
                %Error
                errorcal=abs(((x2-x1)/x2)*100);
                %----------------------------------------------------------%
                %----------------------------------------------------------%
                %Tabla
                app.val(app.iteracion,1)={trad};
                app.val(app.iteracion,2)={trad1};
                app.val(app.iteracion,3)={trad2};
                app.val(app.iteracion,4)={tradd};
                app.val(app.iteracion,5)={trad0};
                app.val(app.iteracion,6)={errorcal};
                app.Tabladevalores.Data=app.val;
                %----------------------------------------------------------%
                %----------------------------------------------------------%
                criterio1=imax==app.iteracion;
                criterio2=errormin>=errorcal;
                logico=criterio1+criterio2;
                x1=x2;
                app.iteracion=app.iteracion+1;
            end
            mensajefinal=sprintf('el programa ah finalizado despues de %i iteraciones',app.iteracion-1);
            msgbox(mensajefinal);
            app.iteracion=1;
            app.val={0,'0','0','0',0};
        end

        % Cell selection callback: Tabladevalores
        function TabladevaloresCellSelection(app, event)
              syms m(x)
              cla(app.Grafica,"reset");
              app.grosor=app.lineagrosor.Value;
              app.tmar=app.tammarcardor.Value;
              hold(app.Grafica,'on');
              indices = event.Indices;
              p=indices(1,1);
              x0=str2num(app.Tabladevalores.Data{p,4});
              x1=str2num(app.Tabladevalores.Data{p,5});
              fx0=str2num(app.Tabladevalores.Data{p,2});
              plot(app.Grafica,app.funcejex,app.funcejey,'-','LineWidth',app.grosor);
            %punto 1--------------------------------------------------------%
            plot(app.Grafica,x0,0,'*','MarkerSize',app.tmar+5,'MarkerEdgeColor','b')
            %punto 2--------------------------------------------------------%
            plot(app.Grafica,x1,0,'o','MarkerSize',app.tmar,'MarkerEdgeColor','k')
            %punto 3
            plot(app.Grafica,x0,fx0,'.','MarkerSize',app.tmar+20,'MarkerEdgeColor','m')
            %Grafica de la pendiente entre fx0 y x1
            % %----------------------------------------------------------%
            fm=(0-fx0)/(x1-x0);
            if x0==0
                b=fx0;
            else
                b=(fx0)-(fm*x0);
            end
            m(x)= b+(fm*x);
            pendx= [app.mini app.maxi];
            pendy= m([app.mini app.maxi]);
            plot(app.Grafica,pendx,pendy,'--r','LineWidth',app.grosor);
            app.Grafica.XGrid='on';
            app.Grafica.YGrid='on';
            app.Grafica.Title.String='Graficacion de las iteraciones';
            app.Grafica.XLabel.String='X';
            app.Grafica.YLabel.String='Y';
            %----------------------------------------------------------%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NewtonrapsonUIFigure = uifigure('Visible', 'off');
            app.NewtonrapsonUIFigure.Name = 'Newton rapson';
            app.Grafica = uiaxes(app.NewtonrapsonUIFigure);
            title(app.Grafica, 'Graficacion de las iteraciones')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            app.Datos = uipanel(app.NewtonrapsonUIFigure);
            app.Datos.Title = 'Datos de entrada';
            app.FuncionFxEditFieldLabel = uilabel(app.Datos);
            app.FuncionFxEditFieldLabel.Text = 'Funcion F(x)';
            app.Funcion = uieditfield(app.Datos, 'text');
            app.Funcion.Value = ' ';
            app.DerivadadelafuncionEditFieldLabel = uilabel(app.Datos);
            app.DerivadadelafuncionEditFieldLabel.Text = 'Derivada de la funcion';
            app.Deriva = uieditfield(app.Datos, 'text');
            app.Deriva.Editable = 'off';
            app.IteracionesaprobarEditFieldLabel = uilabel(app.Datos);
            app.IteracionesaprobarEditFieldLabel.Text = 'Iteraciones a probar';
            app.Niteraciones = uieditfield(app.Datos, 'numeric');
            app.ErrorminimoEditFieldLabel = uilabel(app.Datos);
            app.ErrorminimoEditFieldLabel.Text = 'Error minimo';
            app.Errorminimo = uieditfield(app.Datos, 'numeric');
            app.Errorminimo.ValueDisplayFormat = '%5.2f %%';
            app.Calcular = uibutton(app.Datos, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularPushed, true);
            app.Calcular.Text = 'Calcular y graficar';
            app.X0EditFieldLabel = uilabel(app.Datos);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0 = uieditfield(app.Datos, 'numeric');
            app.Configgrafica = uipanel(app.NewtonrapsonUIFigure);
            app.Configgrafica.Title = 'Graficacion del vector';
            app.LimiteinicialEditFieldLabel = uilabel(app.Configgrafica);
            app.LimiteinicialEditFieldLabel.Text = 'Limite inicial';
            app.Limi = uieditfield(app.Configgrafica, 'numeric');
            app.LimitefinalEditFieldLabel = uilabel(app.Configgrafica);
            app.LimitefinalEditFieldLabel.Text = 'Limite final';
            app.Limf = uieditfield(app.Configgrafica, 'numeric');
            app.GrosordelineasSliderLabel = uilabel(app.Configgrafica);
            app.GrosordelineasSliderLabel.Text = 'Grosor de lineas';
            app.lineagrosor = uislider(app.Configgrafica);
            app.lineagrosor.Limits = [1 4];
            app.lineagrosor.MajorTicks = [1 2 3 4];
            app.lineagrosor.Value = 3;
            app.TamaodemarcadorLabel = uilabel(app.Configgrafica);
            app.TamaodemarcadorLabel.Text = 'Tamaño de marcador';
            app.tammarcardor = uislider(app.Configgrafica);
            app.tammarcardor.Limits = [10 30];
            app.tammarcardor.MajorTicks = [10 20 30 40];
            app.tammarcardor.MajorTickLabels = {'10', '20', '30', '40'};
            app.tammarcardor.Value = 15;
            app.Tabladevalores = uitable(app.NewtonrapsonUIFigure);
            app.Tabladevalores.ColumnName = {'i'; 'F(xi)'; 'F''(Xi)'; 'x0'; 'xi+1'; 'Error'};
            app.Tabladevalores.RowName = {};
            app.Tabladevalores.CellSelectionCallback = createCallbackFcn(app, @TabladevaloresCellSelection, true);
            app.TITULO = uilabel(app.NewtonrapsonUIFigure);
            app.TITULO.Text = 'Metodo de Newton Rapson';
            app.TITULO_2 = uilabel(app.NewtonrapsonUIFigure);
            app.TITULO_2.Text = 'Selecciona solo un renglon de la tabla.';
            app.Image = uiimage(app.NewtonrapsonUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Titulos.png');
            % Show the figure after all components are created
            app.NewtonrapsonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonRapson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewtonrapsonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewtonrapsonUIFigure)
        end
    end
end